classdef FEMLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure              matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SavesettingsMenu            matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        MesherTab                   matlab.ui.container.Tab
        CurrentMeshPanel            matlab.ui.container.Panel
        MeshreadyLampLabel          matlab.ui.control.Label
        MeshreadyLamp               matlab.ui.control.Lamp
        TableSetRegions             matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        DefaultprojPanel            matlab.ui.container.Panel
        ElementtypeButtonGroup      matlab.ui.container.ButtonGroup
        Triangle1stOrderButton      matlab.ui.control.RadioButton
        Triangle2ndOrderButton      matlab.ui.control.RadioButton
        Triangle3rdOrderButton      matlab.ui.control.RadioButton
        ElemTypeAxes                matlab.ui.control.UIAxes
        RunsetupButton              matlab.ui.control.Button
        MeshrefinementPanel         matlab.ui.container.Panel
        FactorSpinnerLabel          matlab.ui.control.Label
        FactorSpinner               matlab.ui.control.Spinner
        RegionsPanel                matlab.ui.container.Panel
        TableEditRegions            matlab.ui.control.Table
        EditmeshButton              matlab.ui.control.Button
        UpdateparamsButton          matlab.ui.control.Button
        PreprocessorTab             matlab.ui.container.Tab
        ProjectSetupPanel           matlab.ui.container.Panel
        ProblemtypeButtonGroup      matlab.ui.container.ButtonGroup
        ElectrostaticButton         matlab.ui.control.RadioButton
        StaticcurrentButton         matlab.ui.control.RadioButton
        MagnetostaticButton         matlab.ui.control.RadioButton
        SymmetryButtonGroup         matlab.ui.container.ButtonGroup
        PlanesymmetricButton        matlab.ui.control.RadioButton
        AxissymmetricButton         matlab.ui.control.RadioButton
        CalculationtypeButtonGroup  matlab.ui.container.ButtonGroup
        IntegrationButton           matlab.ui.control.RadioButton
        ExactformulaButton          matlab.ui.control.RadioButton
        CleanprojectButton          matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        PreprocessingLampLabel      matlab.ui.control.Label
        PreprocessingLamp           matlab.ui.control.Lamp
        RegionparametersPanel       matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        PostprocessorTab            matlab.ui.container.Tab
    end

    
    properties (Access = public)
        projPath = pwd; % Path to project
        projName = 'Default.proj'; % Initial project name
        
        settingsFilePth = [pwd, '\settings.mat'];
        plotsPth = [pwd, '\plots\'];
        resultsPth = [pwd, '\results\'];
    end
    
    properties (Access = private)
       STATIC_E = 0;
       STATIC_C = 1;
       STATIC_M = 2;
       
       PLANE_SYM = 0;
       AXIS_SYM = 1;
       
       COMP_INT = 0;
       COMP_FORM = 1;
       
       problemType = 0;
       symmetry = 0;
       computation = 0;
       
       settingsKeys = {'problemType', 'symmetry', 'computation'};
       settingsVals = {0, 0, 0};
       
    end
    
    methods (Access = private)
        
        function setPathVars(app)
            app.settingsFilePth = [app.projPath, '\settings.mat'];
            app.plotsPth = [app.projPath, '\plots\'];
            app.resultsPth = [app.projPath, '\results\'];
        end
        
        function state = checkButtonState(app, button)
            value = button.Text;
            switch value
                case 'Electrostatic'
                    state = app.STATIC_E;
                case 'Static current'
                    state = app.STATIC_C;
                case 'Magnetostatic'
                    state = app.STATIC_M;
                case 'Plane symmetric'
                    state = app.PLANE_SYM;
                case 'Axis symmetric'
                    state = app.AXIS_SYM;
                case 'Integration'
                    state = app.COMP_INT;
                case 'Exact formula'
                    state = app.COMP_FORM;
            end
        end
       
        % Sets the problem type 
        function setProblemType(app, selectedButton)
            state = app.checkButtonState(selectedButton);
            switch state
                case app.STATIC_E
                    app.problemType = app.STATIC_E;
                case app.STATIC_C
                    app.problemType = app.STATIC_C;
                case app.STATIC_M
                    app.problemType = app.STATIC_M;
            end
        end
    
        % Sets the symmetry type 
        function setSymmetryType(app, selectedButton)
            state = app.checkButtonState(selectedButton);
            switch state
                case app.PLANE_SYM
                    app.symmetry = app.PLANE_SYM;
                case app.AXIS_SYM
                    app.symmetry = app.AXIS_SYM;
            end
        end
    
        % Sets the computation type 
        function setComputationType(app, selectedButton)
            state = app.checkButtonState(selectedButton);
            switch state
                case app.COMP_INT
                    app.computation = app.COMP_INT;
                case app.COMP_FORM
                    app.computation = app.COMP_FORM;
            end
        end
    
        % Updating setting values : 
        function updateSettingsVals(app)
        
            app.settingsVals = [app.problemType, app.symmetry, app.computation];
        
        end
    
        function updateProblemSettings(app)
            
            app.updateSettingsVals()
            settings = containers.Map(app.settingsKeys, app.settingsVals);
            save(app.settingsFilePth, 'settings')
            
        end
    
        function setSetupState(app, state)
            switch state
                case 'Ready'
                    app.SetupOKLamp.Color = [0,1,0];
                case 'Not'
                    app.SetupOKLamp.Color = [1,1,0];
            end
        end
    end
    
    methods (Access = public)
        
        function initComponents(app)
            
            % ElemTypeAxes init :
            title(app.ElemTypeAxes, '')
            ylabel(app.ElemTypeAxes, '')
            xlabel(app.ElemTypeAxes, '')
            app.setElementTypeImg(1)
            
            % Project name init
            app.setProjectTitle()
            
            %% Lamps intializer :
            
            % MeshreadyLamp
            app.MeshreadyLamp.Color = [1,0,0];
            
            % Preprocessed lamp
            app.PreprocessingLamp.Color = [1,0,0];
            
        end
        
        % Sets the element type image
        function setElementTypeImg(app, i)
            img = imread(['./AppFiles/figures/', num2str(i), 'o_triangle.png']);
            imshow(img, 'parent', app.ElemTypeAxes)
        end
        
        % Sets project title 
        function setProjectTitle(app)
            app.ProjectSetupPanel.Title = app.projName;
            app.DefaultprojPanel.Title = app.projName;
        end
        
        
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.projPath = uigetdir(pwd, 'Select project folder');
            pathParts = strsplit(app.projPath, '\');
            app.projName = pathParts{end};
            
            app.setPathVars()
            app.setProjectTitle()
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject;
            app.setProblemType(selectedButton);
            app.updateProblemSettings();
        end

        % Selection changed function: SymmetryButtonGroup
        function SymmetryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SymmetryButtonGroup.SelectedObject;
            app.setSymmetryType(selectedButton);
            app.updateProblemSettings();
        end

        % Selection changed function: CalculationtypeButtonGroup
        function CalculationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationtypeButtonGroup.SelectedObject;
            app.setComputationType(selectedButton);
            app.updateProblemSettings();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.Accelerator = 'S';
            app.SavesettingsMenu.Text = 'Save settings';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.FEMLabUIFigure);
            app.MesherTab = uitab(app.TabGroup);
            app.MesherTab.Title = 'Mesher';
            app.CurrentMeshPanel = uipanel(app.MesherTab);
            app.CurrentMeshPanel.Title = 'Current Mesh ';
            app.MeshreadyLampLabel = uilabel(app.CurrentMeshPanel);
            app.MeshreadyLampLabel.Text = 'Mesh ready';
            app.MeshreadyLamp = uilamp(app.CurrentMeshPanel);
            app.TableSetRegions = uitable(app.CurrentMeshPanel);
            app.TableSetRegions.ColumnName = {'# Nodes'; '# Elements'; 'Element type'};
            app.TableSetRegions.RowName = {};
            app.UIAxes = uiaxes(app.CurrentMeshPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DefaultprojPanel = uipanel(app.MesherTab);
            app.DefaultprojPanel.Title = 'Default.proj';
            app.ElementtypeButtonGroup = uibuttongroup(app.DefaultprojPanel);
            app.ElementtypeButtonGroup.Title = 'Element type :';
            app.Triangle1stOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle1stOrderButton.Text = 'Triangle 1st Order';
            app.Triangle1stOrderButton.Value = true;
            app.Triangle2ndOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle2ndOrderButton.Text = 'Triangle 2nd Order';
            app.Triangle3rdOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle3rdOrderButton.Text = 'Triangle 3rd Order';
            app.ElemTypeAxes = uiaxes(app.ElementtypeButtonGroup);
            title(app.ElemTypeAxes, 'Title')
            xlabel(app.ElemTypeAxes, 'X')
            ylabel(app.ElemTypeAxes, 'Y')
            app.ElemTypeAxes.XTick = [];
            app.ElemTypeAxes.YTick = [];
            app.RunsetupButton = uibutton(app.DefaultprojPanel, 'push');
            app.RunsetupButton.Text = 'Run setup';
            app.MeshrefinementPanel = uipanel(app.DefaultprojPanel);
            app.MeshrefinementPanel.Title = 'Mesh refinement : ';
            app.FactorSpinnerLabel = uilabel(app.MeshrefinementPanel);
            app.FactorSpinnerLabel.Text = 'Factor : ';
            app.FactorSpinner = uispinner(app.MeshrefinementPanel);
            app.FactorSpinner.Value = 1;
            app.RegionsPanel = uipanel(app.DefaultprojPanel);
            app.RegionsPanel.Title = 'Regions : ';
            app.TableEditRegions = uitable(app.RegionsPanel);
            app.TableEditRegions.ColumnName = {'ID'; 'Name'; 'Type'};
            app.TableEditRegions.RowName = {};
            app.TableEditRegions.ColumnEditable = [true false false];
            app.EditmeshButton = uibutton(app.DefaultprojPanel, 'push');
            app.EditmeshButton.Text = 'Edit mesh';
            app.UpdateparamsButton = uibutton(app.DefaultprojPanel, 'push');
            app.UpdateparamsButton.Text = 'Update params';
            app.PreprocessorTab = uitab(app.TabGroup);
            app.PreprocessorTab.Title = 'Preprocessor';
            app.ProjectSetupPanel = uipanel(app.PreprocessorTab);
            app.ProjectSetupPanel.Title = 'Default.proj';
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type : ';
            app.ElectrostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.ElectrostaticButton.Text = 'Electrostatic';
            app.ElectrostaticButton.Value = true;
            app.StaticcurrentButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.StaticcurrentButton.Text = 'Static current';
            app.MagnetostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.MagnetostaticButton.Text = 'Magnetostatic';
            app.SymmetryButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.SymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SymmetryButtonGroupSelectionChanged, true);
            app.SymmetryButtonGroup.Title = 'Symmetry :';
            app.PlanesymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.PlanesymmetricButton.Text = 'Plane symmetric';
            app.PlanesymmetricButton.Value = true;
            app.AxissymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.AxissymmetricButton.Text = 'Axis symmetric';
            app.CalculationtypeButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.CalculationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationtypeButtonGroupSelectionChanged, true);
            app.CalculationtypeButtonGroup.Title = 'Calculation type : ';
            app.IntegrationButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.IntegrationButton.Text = 'Integration';
            app.IntegrationButton.Value = true;
            app.ExactformulaButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.ExactformulaButton.Text = 'Exact formula';
            app.CleanprojectButton = uibutton(app.ProjectSetupPanel, 'push');
            app.CleanprojectButton.Text = 'Clean project';
            app.ProcessButton = uibutton(app.ProjectSetupPanel, 'push');
            app.ProcessButton.Text = 'Process';
            app.StatePanel = uipanel(app.ProjectSetupPanel);
            app.StatePanel.Title = 'State : ';
            app.PreprocessingLampLabel = uilabel(app.StatePanel);
            app.PreprocessingLampLabel.Text = 'Preprocessing';
            app.PreprocessingLamp = uilamp(app.StatePanel);
            app.RegionparametersPanel = uipanel(app.ProjectSetupPanel);
            app.RegionparametersPanel.Title = 'Region parameters : ';
            app.UITable = uitable(app.RegionparametersPanel);
            app.UITable.ColumnName = {'Name'; 'Parameter'};
            app.UITable.RowName = {};
            app.PostprocessorTab = uitab(app.TabGroup);
            app.PostprocessorTab.Title = 'Postprocessor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FEMLabApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end